classdef doublediffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        LambdanmSliderLabel  matlab.ui.control.Label
        LambdanmSlider       matlab.ui.control.Slider
        dmSliderLabel        matlab.ui.control.Label
        dmSlider             matlab.ui.control.Slider
        ammLabel             matlab.ui.control.Label
        ammSlider            matlab.ui.control.Slider
        mSliderLabel         matlab.ui.control.Label
        mSlider              matlab.ui.control.Slider
        Image                matlab.ui.control.Image
    end

    %%双缝衍射
    properties (Access = public)
        Property % Description
        Lambda=500e-9
        d=0.15e-3
        a=30*1e-6
        L=1
        H=0.1
        ScreenX=1048
        ScreenY=350
        x,theta,beta,alpha,x1,x2,I,II
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x=linspace(-app.H/2,app.H/2,app.ScreenX)
            app.theta=atan(app.x/app.L)
            app.beta=pi*app.d*sin(app.theta)/app.Lambda
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])

            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
            
            
        end

        % Value changing function: LambdanmSlider
        function LambdanmSliderValueChanging(app, event)
            app.Lambda = event.Value*1e-9;
            app.beta=pi*app.d*sin(app.theta)/app.Lambda
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])

            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
        end

        % Value changing function: dmSlider
        function dmSliderValueChanging(app, event)
            app.d = event.Value*1e-3;
           
            app.beta=pi*app.d*sin(app.theta)/app.Lambda
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])

            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
        end

        % Value changing function: ammSlider
        function ammSliderValueChanging(app, event)
            app.a = event.Value*1e-6;
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])
            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
        end

        % Value changing function: mSlider
        function mSliderValueChanging(app, event)
            app.L = event.Value;
            app.theta=atan(app.x/app.L)
            app.beta=pi*app.d*sin(app.theta)/app.Lambda
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])

            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, '相对光强')
            app.LambdanmSliderLabel = uilabel(app.UIFigure);
            app.LambdanmSliderLabel.Text = 'Lambda(nm)';
            app.LambdanmSlider = uislider(app.UIFigure);
            app.LambdanmSlider.Limits = [400 760];
            app.LambdanmSlider.ValueChangingFcn = createCallbackFcn(app, @LambdanmSliderValueChanging, true);
            app.LambdanmSlider.Value = 500;
            app.dmSliderLabel = uilabel(app.UIFigure);
            app.dmSliderLabel.Text = 'd(m)';
            app.dmSlider = uislider(app.UIFigure);
            app.dmSlider.Limits = [0.01 1];
            app.dmSlider.ValueChangingFcn = createCallbackFcn(app, @dmSliderValueChanging, true);
            app.dmSlider.Value = 0.15;
            app.ammLabel = uilabel(app.UIFigure);
            app.ammLabel.Text = 'a(mm)';
            app.ammSlider = uislider(app.UIFigure);
            app.ammSlider.Limits = [0.01 1];
            app.ammSlider.ValueChangingFcn = createCallbackFcn(app, @ammSliderValueChanging, true);
            app.ammSlider.Value = 0.03;
            app.mSliderLabel = uilabel(app.UIFigure);
            app.mSliderLabel.Text = '(m)';
            app.mSlider = uislider(app.UIFigure);
            app.mSlider.Limits = [0.1 5];
            app.mSlider.ValueChangingFcn = createCallbackFcn(app, @mSliderValueChanging, true);
            app.mSlider.Value = 1;
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doublediffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end