classdef fresneldiffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        lambdaSliderLabel  matlab.ui.control.Label
        lambdaSlider       matlab.ui.control.Slider
        dmSliderLabel      matlab.ui.control.Label
        dmSlider           matlab.ui.control.Slider
        Image              matlab.ui.control.Image
    end

    
    properties (Access = public)
        %直边菲涅尔衍射
        Property % Description
        ScreenX=1048
        ScreenY=350
        lambda=632.8e-9
        d=10
        x=0.02
        upsilon0
        I,II
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.upsilon0 = linspace(-sqrt(2/(app.lambda*app.d))*app.x,sqrt(2/(app.lambda*app.d))*app.x,app.ScreenX)
            app.I=1/2*((1/2-fresnelc(-app.upsilon0)).^2+(1/2-fresnels(-app.upsilon0)).^2)
            app.II=repmat(app.I,[app.ScreenY 1])
            
            imshow(app.II,'Parent',app.UIAxes)
            plot(app.UIAxes2,linspace(-app.x/2,app.x/2,app.ScreenX),app.I)
        end

        % Value changing function: lambdaSlider
        function lambdaSliderValueChanging(app, event)
            app.lambda = event.Value*1e-9;
            app.upsilon0 = linspace(-sqrt(2/(app.lambda*app.d))*app.x,sqrt(2/(app.lambda*app.d))*app.x,app.ScreenX)
            app.I=1/2*((1/2-fresnelc(-app.upsilon0)).^2+(1/2-fresnels(-app.upsilon0)).^2)
            app.II=repmat(app.I,[app.ScreenY 1])
            
            imshow(app.II,'Parent',app.UIAxes)
            plot(app.UIAxes2,linspace(-app.x/2,app.x/2,app.ScreenX),app.I)
        end

        % Value changing function: dmSlider
        function dmSliderValueChanging(app, event)
            app.d = event.Value;
            app.upsilon0 = linspace(-sqrt(2/(app.lambda*app.d))*app.x,sqrt(2/(app.lambda*app.d))*app.x,app.ScreenX)
            app.I=1/2*((1/2-fresnelc(-app.upsilon0)).^2+(1/2-fresnels(-app.upsilon0)).^2)
            app.II=repmat(app.I,[app.ScreenY 1])
            
            imshow(app.II,'Parent',app.UIAxes)
            plot(app.UIAxes2,linspace(-app.x/2,app.x/2,app.ScreenX),app.I)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, '相对光强')
            app.lambdaSliderLabel = uilabel(app.UIFigure);
            app.lambdaSliderLabel.Text = 'lambda';
            app.lambdaSlider = uislider(app.UIFigure);
            app.lambdaSlider.Limits = [400 760];
            app.lambdaSlider.ValueChangingFcn = createCallbackFcn(app, @lambdaSliderValueChanging, true);
            app.lambdaSlider.Value = 632;
            app.dmSliderLabel = uilabel(app.UIFigure);
            app.dmSliderLabel.Text = 'd/m';
            app.dmSlider = uislider(app.UIFigure);
            app.dmSlider.Limits = [5 15];
            app.dmSlider.ValueChangingFcn = createCallbackFcn(app, @dmSliderValueChanging, true);
            app.dmSlider.Value = 10;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'double.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fresneldiffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end